classdef mpeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end


    properties (Access = private)
        input_video % Храним взятый файл видео
    end

    methods (Access = private)

        function YCbCr_frame = RGB2YCbCr(app,frame)
            [M,N,~] = size(frame);
            YCbCr_frame = zeros(M, N, 3);
            for i = 1:M
                for j = 1:N
                    R = frame(i,j,1);
                    G = frame(i,j,2);
                    B = frame(i,j,3);
                    Y = (0.299*R) + (0.587*G) + (0.114*B);
                    Cb = (-0.168736*R) + (-0.331264*G) + (0.5*B);
                    Cr = (0.5*R) + (-0.418688*G) - (0.081312*B);
                    YCbCr_frame(i,j,1) = Y;
                    YCbCr_frame(i,j,2) = Cb;
                    YCbCr_frame(i,j,3) = Cr;
                end
            end
        end

        function sub_col = sub_color(app,frame)
            sub_col = frame(:,1:2:end);
        end

        function dct_block = dct(app, frame)
            [M,N] = size(frame);
            A = [0.3536   0.3536   0.3536   0.3536   0.3536   0.3536   0.3536   0.3536;
                0.4904   0.4157   0.2778   0.0975  -0.0975  -0.2778  -0.4157  -0.4904;
                0.4619   0.1913  -0.1913  -0.4619  -0.4619  -0.1913   0.1913   0.4619;
                0.4157  -0.0975  -0.4904  -0.2778   0.2778   0.4904   0.0975  -0.4157;
                0.3536  -0.3536  -0.3536   0.3536   0.3536  -0.3536  -0.3536   0.3536;
                0.2778  -0.4904   0.0975   0.4157  -0.4157  -0.0975   0.4904  -0.2778;
                0.1913  -0.4619   0.4619  -0.1913  -0.1913   0.4619  -0.4619   0.1913;
                0.0975  -0.2778   0.4157  -0.4904   0.4904  -0.4157   0.2778  -0.0975;
                ];
            B = [11	21	31	41	51	61	71	81
                21	31	41	51	61	71	81	91
                31	41	51	61	71	81	91	101
                41	51	61	71	81	91	101	111
                51	61	71	81	91	101	111	121
                61	71	81	91	101	111	121	131
                71	81	91	101	111	121	131	141
                81	91	101	111	121	131	141	151];
            dct_block = NaN(M,N);
            for r = 1:8:M
                for c = 1:8:N
                        block = double(frame(r:r+7, c:c+7));
                        dct_block(r:r+7, c:c+7) = round(A * block * A' ./ B,3);
                end
            end
        end

        function dct_block = idct(app, frame)
            [M,N] = size(frame);
            A = [0.3536   0.3536   0.3536   0.3536   0.3536   0.3536   0.3536   0.3536;
                0.4904   0.4157   0.2778   0.0975  -0.0975  -0.2778  -0.4157  -0.4904;
                0.4619   0.1913  -0.1913  -0.4619  -0.4619  -0.1913   0.1913   0.4619;
                0.4157  -0.0975  -0.4904  -0.2778   0.2778   0.4904   0.0975  -0.4157;
                0.3536  -0.3536  -0.3536   0.3536   0.3536  -0.3536  -0.3536   0.3536;
                0.2778  -0.4904   0.0975   0.4157  -0.4157  -0.0975   0.4904  -0.2778;
                0.1913  -0.4619   0.4619  -0.1913  -0.1913   0.4619  -0.4619   0.1913;
                0.0975  -0.2778   0.4157  -0.4904   0.4904  -0.4157   0.2778  -0.0975;
                ];
            B = [11	21	31	41	51	61	71	81
                21	31	41	51	61	71	81	91
                31	41	51	61	71	81	91	101
                41	51	61	71	81	91	101	111
                51	61	71	81	91	101	111	121
                61	71	81	91	101	111	121	131
                71	81	91	101	111	121	131	141
                81	91	101	111	121	131	141	151];
            dct_block = NaN(M,N);
            for r = 1:8:M
                for c = 1:8:N
                        block = double(frame(r:r+7, c:c+7));
                        block = B .* block;
                        dct_block(r:r+7, c:c+7) =   A' * block * A ;
                        
                end
            end
        end
        function RGB_frame = YCbCr2RGB(app,frame)
            [M,N,~] = size(frame);
            RGB_frame = zeros(M, N, 3);
            for i = 1:M
                for j = 1:N
                    Y = double(frame(i,j,1));
                    Cb = double(frame(i,j,2));
                    Cr = double(frame(i,j,3));
                    R = Y + (1.402*(Cr));
                    G = Y-(0.34414*(Cb))-(0.71414*(Cr));
                    B = Y+(1.772*(Cb));
                    RGB_frame(i,j,1) = R;
                    RGB_frame(i,j,2) = G;
                    RGB_frame(i,j,3) = B;
                end
            end
        end

        function decoded = irle(app,input)
            decoded = [];
            for i = 1:2:length(input)
                symbol = input(i);
                count = input(i+1);
                decoded = [decoded, repmat(symbol, 1, count)];
            end
        end

        function Output=rle(app,Input)
            L=length(Input);
            j=1;
            k=1;
            i=1;
            while i<2*L
                comp=1;
                for j=j:L
                    if j==L
                        break
                    end
                    if Input(j)==Input(j+1)
                        comp=comp+1;
                    else
                        break
                    end
                end
                Output(k+1)=comp;
                Output(k)=Input(j);
                if j==L && Input(j-1)==Input(j)
                    break
                end
                i=i+1;
                k=k+2;
                j=j+1;
                if j==L
                    % if mod(L,2)==0
                    %     Output(k+1)=1;
                    %     Output(k)=Input(j);
                    % else
                    Output(k+1)=1;
                    Output(k)=Input(j);
                    % end
                    break
                end
            end
        end

        function interp = interpolation(app, frame)
            [M,N,~] = size(frame);
            % for i = 1:N
            %     interp(:,i*2-1) = frame(:,i);
            % end
            interp = imresize(frame,[M,N*2],"lanczos3");
            % for i = 1:M
            %     for j = 1:2:N*2-2
            %         interp(i, j + 1) = (interp(i, j) + interp(i, j + 2))/2;
            %     end
            % end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            set(app.Button, 'Enable', 'off');
            set(app.Button_2, 'Enable', 'off');
            

            [file,path] = uigetfile('*.mp4','Выберите видео');
            full_path = fullfile(path, file);

            vid = VideoReader(full_path); % Замените на имя вашего файла видео
            M = vid.Height; N = vid.Width;
            clear file
            audio = audioread(full_path);
            mono = round(rle(app,audio(:,1)),3);
            numFrames = vid.NumFrames; % Получаем количество кадров в видео

            f = waitbar(0,'Please wait...');
            tic
            timerVal = tic;
            for p = 1:numFrames
                frame = read(vid, p); % Считываем текущий кадр
                frame = RGB2YCbCr(app,im2double(frame));
                color(:,:,1) = imresize(frame(:,:,1),[M/2,N/2],"lanczos3");
                color(:,:,2) = round(cat(2,sub_color(app,imresize(frame(:,:,2),[M/2,N/2],"lanczos3")),sub_color(app,imresize(frame(:,:,3),[M/2,N/2],"lanczos3"))), 2);

                if mod(p,15) == 1

                    frame1 = color;
                    [color(:,:,1)] = dct(app,imresize(frame(:,:,1),[M/2,N/2],"lanczos3")); % Y
                 
                    
                    color1 = color(:);
                    color1 = round(rle(app,color1),3);
                    % figure;imshow(color(:,:,1));
                    if p==1
                        [file,path] = uiputfile('*.txt', 'Дайте имя файлу и укажите путь');
                        full_path = fullfile(path, file);
                        writematrix(color1, full_path);
                    else
                        writematrix(color1, full_path,'WriteMode', 'append');
                    end

                else
                    col = color - frame1;
                    color1 = round(rle(app,col(:)),3);
                    writematrix(color1, full_path, 'WriteMode', 'append');
                    frame1 = color;
                end
    
                f = waitbar(p/numFrames, f, 'Please wait...');
            end
             
            save('param.mat','M',"N","numFrames",'full_path',"mono");

            zip('test',full_path);
            toc;
            elapsedTime = toc(timerVal);
            app.Label.Text = (num2str(round(elapsedTime,3)));

            f = waitbar(1, f, 'Closing...');
            close(f);
            delete(f);

            set(app.Button, 'Enable', 'on');
            set(app.Button_2, 'Enable', 'on');
           
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            set(app.Button, 'Enable', 'off');
            set(app.Button_2, 'Enable', 'off');


            [file,path] = uigetfile('*.zip', 'Раззипуйте пж');
            full_path = fullfile(path, file);
            unzip(full_path);

            M = load('param.mat','M').M/2;
            N = load('param.mat','N').N/2;
            numFrames = load('param.mat','numFrames').numFrames;
            full_path = load('param.mat','full_path').full_path;
            % mono = load('param.mat','mono').mono;
            [file,path] = uiputfile('*.mp4', 'Дайте имя файлу и укажите путь');
            full_path2 = fullfile(path, file);

            outputVideo = VideoWriter(full_path2,'MPEG-4');
            outputVideo.FrameRate = 24; % Установка частоты кадров
            open(outputVideo);
            % audio = irle(app,mono);
            
            f = waitbar(0,'Please wait...');
            % Запись каждого кадра в видео
            tic;
            timerVal = tic;
            fid = fopen(full_path, 'r'); %
            for p = 1:numFrames

                line = fgetl(fid); % Читаем одну строку из файла
                if line == -1 % Если достигнут конец файла
                    break; % Выходим из цикла
                end
                delim = ',';
                vec = strsplit(line, delim);
                vec = irle(app,str2double(vec));


                if mod(p,15) == 1
                    vector = reshape(vec,[M,N,2]);
                    [vector(:,:,1)] = idct(app,vector(:,:,1));
                    tmp = vector;
                    frame(:,:,1) = imresize(vector(:,:,1),[M*2,N*2],"lanczos3");
                    frame(:,:,2) = imresize(interpolation(app,vector(:,1:end/2,2)),[M*2,N*2],"lanczos3");
                    frame(:,:,3) = imresize(interpolation(app,vector(:,end/2+1:end,2)),[M*2,N*2],"lanczos3");
                    fram = YCbCr2RGB(app,frame);
                    % figure;
                    % imshow(fram);
                    % frame = vector;
                else
                    tmp = tmp + reshape(vec,[M,N,2]);
                    difference(:,:,1) = imresize(tmp(:,:,1),[M*2,N*2],"lanczos3");
                    difference(:,:,2) = imresize(interpolation(app,tmp(:,1:end/2,2)),[M*2,N*2],"lanczos3");
                    difference(:,:,3) = imresize(interpolation(app,tmp(:,end/2+1:end,2)),[M*2,N*2],"lanczos3");
                    fram = YCbCr2RGB(app,difference);

                    % figure;
                    % imshow(imadjust(fram, [0.1 0.9], [0 1]));
                end
                
                % Запись видеофрейма с объединенным аудио
                writeVideo(outputVideo, im2uint8(imadjust(fram, [0.1 0.9], [0 1])));
                % writeVideo(outputVideo, im2uint8(imadjust(fram, [0.1 0.9], [0 1])));
                f = waitbar(p/numFrames, f, 'Please wait...');
                
            end
            
            fclose(fid); % Закрываем файл
            toc;
            elapsedTime = toc(timerVal);
            app.Label.Text = (num2str(round(elapsedTime,3))); 

            f = waitbar(1, f, 'Closing...');
            close(f);
            delete(f);

            clear RGB_frames;

            % Закрытие объекта VideoWriter
            close(outputVideo);
            clear outputVideo

            set(app.Button, 'Enable', 'on');
            set(app.Button_2, 'Enable', 'on');
            
            
        end

        % Callback function
        function Button_3Pushed(app, event)

        end

        % Callback function
        function Button_4Pushed(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '095eebf0eaad40862d700f5dbbf0d.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1626727242_30-kartinkin-com-p-krasno-chernii-zadnii-fon-krasivo-33.jpg');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сжать видео';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Восстановить видео';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Время выполнения';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mpeg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end